classdef VollImBilde < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        WebcamwhlenDropDown       matlab.ui.control.DropDown
        WebcamwhlenDropDownLabel  matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        mitte                     matlab.ui.control.Button
        FolgenButton              matlab.ui.control.StateButton
        h1VollimBildeV01h1Label   matlab.ui.control.Label
        untenrechts               matlab.ui.control.Button
        untenlinks                matlab.ui.control.Button
        obenrechts                matlab.ui.control.Button
        obenlinks                 matlab.ui.control.Button
        canvas                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Cam % WebCamobjekt
        Ardu %Arduinoobjekt
        Pan %Servoobjekt für Panbewegung
        Tilt %Servoobjekt für Tiltbewegung
        Detector %Bilderkennung
        VisionType % Description
        Mounting % Davon hängen auch Buttons(Sollwerte) ab!
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Arduino initialisieren
            app.Cam = webcam(2); %evtl noch als Dropdown auswählbar machen
            app.Ardu = arduino('COM6', 'Nano33IoT', 'Libraries', 'Servo');
            app.Pan = servo(app.Ardu, 'D2');
            app.Tilt = servo(app.Ardu, 'D3');
            writePosition(app.Tilt, 0.5);
            writePosition(app.Pan, 0.35);
            %Dropdown für WebCam initalisieren?
            %Bilderkennung initalisieren
            app.VisionType = 'peopleACF';
            switch VISION_TYP
                % Erkennt Gesichter/Oberkörper gut: Rückgabe ist eher das Gesicht
                case 'cascade'
                    app.Detector = vision.CascadeObjectDetector(...
                        'ClassificationModel', 'UpperBody');
                case 'people'
                    % Erkennt Oberkörper
                    app.Detector = vision.PeopleDetector(...
                        'ClassificationModel', 'UprightPeople_96x48');
                case 'peopleACF'
                    % ACF-Algorithmus, erkennt ganze Personen oder Oberkörper
                    app.DetectorF = peopleDetectorACF;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.canvas = uiaxes(app.UIFigure);
            title(app.canvas, 'Title')
            xlabel(app.canvas, 'X')
            ylabel(app.canvas, 'Y')
            zlabel(app.canvas, 'Z')
            app.obenlinks = uibutton(app.UIFigure, 'push');
            app.obenlinks.Text = 'oben links';
            app.obenrechts = uibutton(app.UIFigure, 'push');
            app.obenrechts.Text = 'oben rechts';
            app.untenlinks = uibutton(app.UIFigure, 'push');
            app.untenlinks.Text = 'unten links';
            app.untenrechts = uibutton(app.UIFigure, 'push');
            app.untenrechts.Text = 'unten rechts';
            app.h1VollimBildeV01h1Label = uilabel(app.UIFigure);
            app.h1VollimBildeV01h1Label.Interpreter = 'html';
            app.h1VollimBildeV01h1Label.Text = '<h1>Voll im Bilde V0.1.<h1>';
            app.FolgenButton = uibutton(app.UIFigure, 'state');
            app.FolgenButton.Text = 'Folgen';
            app.mitte = uibutton(app.UIFigure, 'push');
            app.mitte.Text = 'mitte';
            app.Image = uiimage(app.UIFigure);
            app.WebcamwhlenDropDownLabel = uilabel(app.UIFigure);
            app.WebcamwhlenDropDownLabel.Text = 'Webcam wählen ..';
            app.WebcamwhlenDropDown = uidropdown(app.UIFigure);
            app.WebcamwhlenDropDown.Items = {'webcamlist'};
            app.WebcamwhlenDropDown.Value = 'webcamlist';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VollImBilde

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end